classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Button_3                    matlab.ui.control.Button
        OutputinWordsTextAreaLabel  matlab.ui.control.Label
        OutputinWordsTextArea       matlab.ui.control.TextArea
        ButtonGroup                 matlab.ui.container.ButtonGroup
        OthersButton                matlab.ui.control.RadioButton
        ExperimentCButton           matlab.ui.control.RadioButton
        ExperimentBButton           matlab.ui.control.RadioButton
        ExperimentAButton           matlab.ui.control.RadioButton
        EditField_2                 matlab.ui.control.EditField
        Label_2                     matlab.ui.control.Label
        EditField                   matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        Button                      matlab.ui.control.Button
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: EditField, OutputinWordsTextArea, UIAxes, 
        % UIAxes2, UIAxes3
        function EditFieldValueChanged(app, event)
            global framevalue
            framevalue = app.EditField.Value;
            app.UIAxes.XLim = [0.04 framevalue*0.08];
            app.UIAxes2.XLim = [0.04 framevalue*0.08];
            app.UIAxes3.XLim = [0.04 framevalue*0.08];
        end

        % Callback function
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            pause(5)
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            
            %% 定义参数 
            adcnum=256;
            chirpnum=255;
            framebytes=adcnum*chirpnum*3*4*2*2;
            range_min=1;
            range_max=128;
            res_r=0.0499;
            res_v=0.0278;
            res_t=0.04;
            V=(-128:127)*res_v;
            R=(1:128)*res_r;
            T=(1:250)*res_t;

            global saveName
            saveName=app.EditField_2.Value;

            if exist(['D:\RadarProcessing\RawData\',saveName,'_Raw_0.bin'],'file')
            	error(['文件【',saveName,'】已存在，请重命名或删除原文件']);
            end
            global ModelNum
            %Mdl=load('TrainedModel.mat').oldMdl;
            ModelNum2=ModelNum;
            switch ModelNum2
                case 1
                    Mdl=load('TrainedModelA.mat').oldMdl;
                    ModelName='A';
                case 2
                    Mdl=load('TrainedModelB.mat').oldMdl;
                    ModelName='B';
                case 3
                    Mdl=load('TrainedModelC.mat').oldMdl;
                    ModelName='C';
                case 4
                    Mdl=load('TrainedModel.mat').oldMdl;
                    ModelName='None';
            end     


            vf=[];
            rf=[];
            PLOT_ON=1;
            ANSYS_ON=0;

            %%
            k=1;
            oldframe=0;
            oldframe_stft=0;

            run('RSTD_Interface_Example.m');

            cellArrayText{1}=sprintf('识别任务：%s\n开始采集：%s',ModelName,saveName);
            app.OutputinWordsTextArea.Value = cellArrayText;

            starttime=clock;
            tic
            disp(['STARTTIME: ',num2str(starttime(4)),':',num2str(starttime(5)),':',num2str(starttime(6))]);

            % 保存文件的名字
            fileName=['D:\RadarProcessing\RawData\',saveName,'_Raw_0.bin'];
            % 检测是否生成
            while 1
                if size(dir(fileName),1)~=0
                    break;
                end
            end
            outerbytes=0;
            conframe=0;

            raw_idx=1;
            file=zeros(100,5);
            timetable=strings(100,1);
            vf_ansys=[];vf_old=[];vf_con=[];
            rf_ansys=[];rf_old=[];rf_con=[];

            label=0;
            toc
            fid = fopen(fileName,'r');
            processID=2;

            %%
            while toc<app.EditField.Value*0.08+5 % 检测时长
                % read size of data real-time (2-7ms)
                filebytes=dir(fileName).bytes-outerbytes;
                fileframe=filebytes/(framebytes);
                newframe=floor(fileframe)+conframe;

                if newframe>oldframe+1
                    file(k,1)=toc;
                    file(k,2)=newframe;
                    file(k,3)=newframe-oldframe;

                    adcData=fread(fid, (newframe-oldframe)*(framebytes/2), 'int16'); %按顺序读取
                    adcData = reshape(adcData, 4*2, []);
                    adcData = adcData([1,2,3,4],:) + sqrt(-1)*adcData([5,6,7,8],:);

                    RX1_DATA = reshape(adcData(1,:),adcnum,3,chirpnum,newframe-oldframe);
                    TX1_DATA = squeeze(RX1_DATA(:,1,:,:));

                    [vf,rf]=RealTimeProcess_STFT(TX1_DATA,adcnum,chirpnum,newframe-oldframe,range_min,range_max);

                    %分类
                    if size(vf,2)<40
                        vf_con=[vf_con,vf];
                        rf_con=[rf_con,rf];
                        if size(vf_con,2)>40
                            vf=vf_con;vf_con=[];
                            rf=rf_con;rf_con=[];
                        end
                    end


                    if size(vf,2)>40
                        if size(vf_old,2)<40
                            vf_old=[vf_old,vf];
                            rf_old=[rf_old,rf];
                        else
                            vf_ansys=[vf_old,vf];
                            rf_ansys=[rf_old,rf];
                            idx_ansys=size(vf_ansys,2);
                            labels=zeros(idx_ansys-79,1);
                            for i_ansys=1:idx_ansys-79
                                vf_test=vf_ansys(:,[0:79]+i_ansys);
                                rf_test=rf_ansys(range_min:range_max,[0:79]+i_ansys);
                                feature=extractFeature_self(vf_test,rf_test);
                                labels(i_ansys) = predict(Mdl,feature(1:78));%多次预测
                            end
                            label=mode(labels);%取预测的最多情况
                            vf_old=vf;
                            rf_old=rf;
                            ANSYS_ON=1;
                        end
                    end

                    %newframe_stft=oldframe_stft+(newframe-oldframe)*2-1;
                    newframe_stft=oldframe_stft+(newframe-oldframe)*2;%补偿后
                    if ANSYS_ON
                        realtime=clock;% etime(t1,t2)
                        toc_front=(oldframe_stft+1)*res_t;
                        time_front(3)=starttime(6)+toc_front;
                        time_front(3)=time_front(3)-60*(time_front(3)>60);
                        time_front(2)=starttime(5)+1*(time_front(3)>60);
                        time_front(1)=starttime(4)+1*(time_front(2)>60);

                        toc_behind=(newframe_stft)*res_t;
                        time_behind(3)=starttime(6)+toc_behind;
                        time_behind(3)=time_behind(3)-60*(time_behind(3)>60);
                        time_behind(2)=starttime(5)+1*(time_behind(3)>60);
                        time_behind(1)=starttime(4)+1*(time_behind(2)>60);

                        delay=realtime(6)-time_behind(3);
                        delay=delay+60*(delay<0);

%                         disp(['REAL_T: ',num2str(realtime(4)),':',num2str(realtime(5)),':',num2str(realtime(6)),'    ',...
%                             'PROCESS_T:',num2str(time_front(1)),':',num2str(time_front(2)),':',num2str(time_front(3)),' ~ ',...
%                             num2str(time_behind(1)),':',num2str(time_behind(2)),':',num2str(time_behind(3)),'    ',...
%                             'RELATIVE_T:',num2str(toc_front),'s ~ ',num2str(toc_behind),'s    ',...
%                             'LABEL:',num2str(label),'  DELAY:',num2str(delay)]);

                        cellArrayText{processID}=sprintf('(%s) 时间：%s-%ss 预测：%s', ...
                            num2str(processID-1),num2str(toc_front),num2str(toc_behind),num2str(label));
                        app.OutputinWordsTextArea.Value = cellArrayText;
                        drawnow
                        
                        ANSYS_ON=0;
                        processID=processID+1;
                    end

                    % PLOT
                    if PLOT_ON
                        % range map
                        %imagesc(app.UIAxes,T,R,10*log10(abs(rf(1:128,:))));
                        imagesc(app.UIAxes,([oldframe_stft+1:newframe_stft])*res_t,[1:size(rf,1)]*res_r,10*log10(abs(rf)));
                        hold(app.UIAxes,'on')
                        drawnow

                        imagesc(app.UIAxes2,([oldframe_stft+1:newframe_stft])*res_t,[-size(vf,1)/2:size(vf,1)/2-1]*res_v,10*log10(abs(vf)));
                        hold(app.UIAxes2,'on')
                        drawnow

%                         if k>1 %重叠
%                             plot(app.UIAxes3,[newframe_stft-idx_ansys+1,newframe_stft]*res_t,[label,label],'Color','red','LineWidth',2);                
%                             hold(app.UIAxes3,'on')
%                         end

                        if k>1 %不重叠
                            plot(app.UIAxes3,[oldframe_stft+1,newframe_stft]*res_t,[label,label],'Color','red','LineWidth',2);
                            hold(app.UIAxes3,'on')
                            drawnow
                            if k==2
                                plot(app.UIAxes3,[1,oldframe_stft]*res_t,[label,label],'Color','red','LineWidth',2);
                                hold(app.UIAxes3,'on')
                                drawnow
                            end
                        end
                    end


                    file(k,4)=toc;
                    file(k,5)=file(k,4)-file(k,1);
                    t=clock;
                    timetable(k)=[num2str(t(4)),' : ',num2str(t(5)),' : ',num2str(t(6))];
                    oldframe=newframe;
                    oldframe_stft=newframe_stft;
                    k=k+1;
                end

                if oldframe>340+conframe
                    %outerbytes=framebytes-mod(filebytes,framebytes);%溢出的字节
                    outerbytes=0;
                    fclose('all');
                    fileName=['D:\RadarProcessing\RawData\',saveName,'_Raw_',num2str(raw_idx),'.bin'];
                    fid = fopen(fileName,'r');
                    fseek(fid,outerbytes,'bof');
                    raw_idx=raw_idx+1;
                    conframe=oldframe;
                    %close all
                end

            end
            cellArrayText{processID}=sprintf('采集完成');
            fclose('all');
            %close all
            app.OutputinWordsTextArea.Value = cellArrayText;
            drawnow

            %delete(['D:\RadarProcessing\RawData\',saveName,'_Raw_0.bin']);
            %delete(['D:\RadarProcessing\RawData\',saveName,'_Raw_1.bin']);
            %delete(['D:\RadarProcessing\RawData\',saveName,'_Raw_2.bin']);
            delete(['D:\RadarProcessing\RawData\',saveName,'_LogFile.txt']);
            delete(['D:\RadarProcessing\RawData\',saveName,'_Raw_LogFile.csv']);

        end

        % Callback function
        function Button_2Pushed(app, event)

        end

        % Callback function
        function Button_3ValueChanged(app, event)

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.EditField_2.Value=datestr(datetime('now'),'yyyy-mm-dd-HH-MM-SS');
            %app.EditField_2.Value=datestr(datetime('now'),'mm-dd-HH-MM-SS')

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            global ModelNum
            switch selectedButton 
                case app.ExperimentAButton
                    ModelNum=1;
                case app.ExperimentBButton
                    ModelNum=2;                    
                case app.ExperimentCButton
                    ModelNum=3;
                case app.OthersButton
                    ModelNum=4;
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Range Map')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Range (m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 6.3872];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [80 130];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Doppler Map')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Doppler (m/s)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YLim = [-3.5584 3.5306];
            app.UIAxes2.CLim = [80 130];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Classification Results')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Predicted Labels')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.YLim = [0 10];
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始采集';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '采集帧数';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 500;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '文件命名';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = 'file1';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '采集任务';
            app.ExperimentAButton = uiradiobutton(app.ButtonGroup);
            app.ExperimentAButton.Text = 'Experiment A';
            app.ExperimentAButton.Value = true;
            app.ExperimentBButton = uiradiobutton(app.ButtonGroup);
            app.ExperimentBButton.Text = 'Experiment B';
            app.ExperimentCButton = uiradiobutton(app.ButtonGroup);
            app.ExperimentCButton.Text = 'Experiment C';
            app.OthersButton = uiradiobutton(app.ButtonGroup);
            app.OthersButton.Text = 'Others';
            app.OutputinWordsTextArea = uitextarea(app.UIFigure);
            app.OutputinWordsTextArea.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.OutputinWordsTextAreaLabel = uilabel(app.UIFigure);
            app.OutputinWordsTextAreaLabel.Text = 'Output in Words ';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '时间戳';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end